classdef Sensitivity_Analysis_Copia_Seguridad_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UITable3              matlab.ui.control.Table
        UITable2              matlab.ui.control.Table
        UITable               matlab.ui.control.Table
        FormulaDropDown       matlab.ui.control.DropDown
        FormulaDropDownLabel  matlab.ui.control.Label
        RunButton             matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            switch app.FormulaDropDown.Value
                case 'Michaelis Menten'
                    syms r rmax k s Real
                    p_list=[rmax;k];
                    x_list=[s];
                    x_inv=[s];
                    eqn_list=[r==rmax*s/(k+s)];
                    var_list=[r];
                    pnum=[3;5];
                    xnum={0:0.1:20};
                    x_Fix=[10];
                    multi = false;
                case 'Quadratic function'
                    syms y a b x c Real
                    p_list=[a;b;c];
                    x_list=[x];
                    x_inv=[x];
                    eqn_list=[y==a*x^2+b*x+c];
                    var_list=[y];
                    pnum=[3;5;4];
                    xnum={0:0.1:20};
                    x_Fix=[10];
                    multi = false;
                case 'Linear function'
                    syms y a b x Real
                    p_list=[a;b];
                    x_list=[x];
                    x_inv=[x];
                    eqn_list=[y==a*x+b];
                    var_list=[y];
                    pnum=[3;5];
                    xnum={0:0.1:20};
                    x_Fix=[10];
                    multi = false;
                case 'Bio-reactor Model'
                    syms X S S_in y mu_max K D Real
                    mu = mu_max*S/(S+K);

                    p_list = [mu_max;K;y];
                    x_list = [D;S_in];
                    x_inv = [S_in;D];
                    var_list = [X;S];
                    eqn_list  = [(mu-D)*X;D*(S_in-S)-y*mu*X];

                    xnum1=0:0.01:0.49;
                    xnum2=0:0.01:10;
                    xnum={xnum1; xnum2};
                    x_Fix=[5;0.3];
                    pnum = [0.5;0.01;2];
                    multi = true;
                case 'Personalized'
                    [file,path] = uigetfile('*.m');
                    if isequal(file,0)
                        disp('User selected Cancel');
                    else
                        disp(['User selected ', fullfile(path,file)]);
                    end
                    run(file)
            end

            sol = solve(eqn_list,var_list);

            if isstruct(sol)==0
                [s1,s2]=size(sol);
                for i=1:s1
                    sol_eq_p = sol(i);
                    sol_eq(i) = sol_eq_p(end);
                end
            end

            if isstruct(sol)==1
                sol=struct2cell(sol);
                [s1,s2]=size(sol);
                for i=1:s1
                    sol_eq_p = sol{i};
                    sol_eq(i) = sol_eq_p(end);
                end
            end

            for i=1:length(eqn_list)
                for k=1:length(p_list)
                    df=diff(sol_eq(i),p_list(k));
                    WR(i,k)=simplify(df*p_list(k)/sol_eq(i));
                end
            end

            W=WR.'*WR;
            W_simp = simplifyFraction(W);
            [sz1,sz2] = size(W);
            W_int = sym(zeros(sz1,sz2,10));

            s = 1;
            for j=1:length(x_list)
                W_int_p=int(W_simp,x_list(j));
                W_int(:,:,s) = W_int(:,:,s) + W_int_p;
                s=s+1;
            end
            
            W_sum = sum(W_int,3);
            assume(W_sum,'real')

            W_2=subs(W_sum, p_list, pnum);
            W_plot=W_2;

            for i=1:length(x_list)
                x_max=max(xnum{i});
                x_min=min(xnum{i});
                W_max_p=subs(W_2, x_list(i), x_max);
                W_max(:,:,i)=subs(W_max_p, x_inv(i),x_Fix(i));
                W_min_p=subs(W_2, x_list(i), x_min);
                W_min(:,:,i)=subs(W_min_p,x_inv(i),x_Fix(i));
            end

            W_dif=W_max-W_min;
            W_sym=double(sum(W_dif,3));

            [eigenvec,eigenvals] = eig(W_sym);

            %Maximal deviation
            for i=1:length(pnum)
                pmax(i)=pnum(i)+pnum(i)*eigenvec(i,end);
            end
            pmax=double(pmax);
            %Minimal deviation
            for i=1:length(pnum)
                pmin(i)=pnum(i)+pnum(i)*eigenvec(i,1);
            end
            pmin=double(pmin);
            %Average deviation
            for i=1:length(pnum)
                pavg(i)=pnum(i)+pnum(i)*sqrt(length(p_list))/length(p_list);
            end

            for i=1:length(eqn_list)
                for j=1:length(x_list)
                    cur_eq=sol_eq(i);
                    %Automated deviation
                    f_norm1 = subs(cur_eq,p_list,pnum);
                    f_norm2 = subs(f_norm1,x_list(j),xnum{j});
                    f_norm{j} = subs(f_norm2,x_inv(j),x_Fix(j));

                    f_max1 = subs(cur_eq,p_list,pmax');
                    f_max2 = subs(f_max1, x_list(j),xnum{j});
                    f_max{j} = subs(f_max2,x_inv(j), x_Fix(j));

                    f_min1 = subs(cur_eq, p_list, pmin');
                    f_min2 = subs(f_min1, x_list(j),xnum{j});
                    f_min{j} = subs(f_min2, x_inv(j), x_Fix(j));

                    f_avg1 = subs(cur_eq, p_list, pavg');
                    f_avg2 = subs(f_avg1, x_list(j),xnum{j});
                    f_avg{j} = subs(f_avg2, x_inv(j), x_Fix(j));
                end
            end
            for i=1:length(eqn_list)
                %Automated ploting
                plot(app.UIAxes, xnum{i}, f_norm{i}, 'lineWidth', 1.25)
                hold(app.UIAxes,'on')
                plot(app.UIAxes,xnum{i}, f_max{i}, 'lineWidth', 1.25)
                plot(app.UIAxes,xnum{i}, f_min{i}, 'lineWidth', 1.25)
                plot(app.UIAxes,xnum{i}, f_avg{i}, 'lineWidth', 1.25)
                grid(app.UIAxes,'on')
                legend(app.UIAxes,'Normal', 'Max', 'Minimum', 'Average', 'location','best')
                xlabel(app.UIAxes,'Possible values of all input variable')
                ylabel(app.UIAxes,'Deviations of the formula ' + string(i), 'LineWidth', 2)
                hold(app.UIAxes,'off')
            end
            if multi==true
                for i=1:length(eqn_list)
                %Automated ploting
                figure(i)
                plot(xnum{i}, f_norm{i}, 'lineWidth', 1.25)
                hold('on')
                plot(xnum{i}, f_max{i}, 'lineWidth', 1.25)
                plot(xnum{i}, f_min{i}, 'lineWidth', 1.25)
                plot(xnum{i}, f_avg{i}, 'lineWidth', 1.25)
                grid('on')
                legend('Normal', 'Max', 'Minimum', 'Average', 'location','best')
                xlabel('Possible values of all input variable')
                ylabel('Deviations of the formula ' + string(i), 'LineWidth', 2)
                hold('off')
            end
            end
            %Parameter influence
            for i=1:length(x_list)
                tot_range=length(xnum{1});
                for l=1:tot_range
                    xvals=xnum{1};
                    w_max_p=subs(W_plot,x_list(i),xvals(l)');
                    w_max=subs(w_max_p,x_inv(i),x_Fix(i));
                    w_max=double(w_max);
                    w_min_p=subs(W_plot,x_list(i),0);
                    w_min=subs(w_min_p,x_inv(i),x_Fix(i));
                    w_min=double(w_min);
                    w_b(:,:,i)=w_max-w_min;
                    w_b=sum(w_b,3);
                    [eigenvec2,~] = eig(w_b);
                    for k=1:length(eigenvec2)
                        vm(l,k)=eigenvec2(k,end);
                    end
                end
            end

            for q=1:length(eigenvec2)
                plot(app.UIAxes2,xvals, vm(:,q), 'LineWidth',1.25)
                hold(app.UIAxes2,'on')
            end
            hold(app.UIAxes2,"off")
            grid(app.UIAxes2,"on")
            title(app.UIAxes2,'Analysis of the influence over x for different values of max x')
            legend(app.UIAxes2,string(p_list), 'location', 'best')
            xlabel(app.UIAxes2,'Max values of x', 'LineWidth', 2)
            ylabel(app.UIAxes2,'Influence of each factor', 'LineWidth', 2)

            app.UITable.Data=table(string(p_list),pnum, pmax', pmin', pavg');
            app.UITable2.Data=table(string(sol_eq.'));
            Sens = subs(WR, p_list, pnum);
            Sens = subs(Sens, x_inv, x_Fix);
            app.UITable3.Data=table(string(p_list), string(WR).', string(Sens).');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Deviations of the formulas')
            ylabel(app.UIAxes, 'Deviation')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Influence')
            ylabel(app.UIAxes2, 'Influence')
            zlabel(app.UIAxes2, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FormulaDropDownLabel = uilabel(app.UIFigure);
            app.FormulaDropDownLabel.Text = 'Formula';
            app.FormulaDropDown = uidropdown(app.UIFigure);
            app.FormulaDropDown.Items = {'Michaelis Menten', 'Quadratic function', 'Linear function', 'Bio-reactor Model', 'Personalized'};
            app.FormulaDropDown.Value = 'Michaelis Menten';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Original Value'; 'Maximal Value'; 'Minimal Value'; 'Average Value'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Equations'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Parameter'; 'Normalized Sensitivity'; 'Numerical Substitution'};
            app.UITable3.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sensitivity_Analysis_Copia_Seguridad_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end